classdef p3_convergence_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        ZoomFrom                matlab.ui.control.NumericEditField
        ZoomFromEditFieldLabel  matlab.ui.control.Label
        ZoomTo                  matlab.ui.control.NumericEditField
        ZoomToEditFieldLabel    matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        ExponentialButton       matlab.ui.control.Button
        UniformButton           matlab.ui.control.Button
        NormalButton            matlab.ui.control.Button
        distribution            matlab.ui.control.UIAxes
        Data                    matlab.ui.control.UIAxes
        convergence             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, pcap, acap, mcap, dcap] = p3_convergence_exponential();
        plot(app.Data,a:1:b,pcap(a:1:b),a:1:1000,acap(a:1:1000),a:1:b,mcap(a:1:b),'magenta')
         plot(app.distribution,0:0.00001:2,dcap,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 2]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end

        % Callback function
        function GenerateButtonPushed(app, event)


        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, pcap, acap, mcap, dcap] = p3_convergence_normal();
        plot(app.Data,a:1:b,pcap(a:1:b),a:1:1000,acap(a:1:1000),a:1:b,mcap(a:1:b),'magenta')
        plot(app.distribution, -1 :0.00001:1,dcap,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[-1 1]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end

        % Button pushed function: UniformButton
        function UniformButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, pcap, acap, mcap, dcap] = p3_convergence_uniform();
        plot(app.Data,a:1:b,pcap(a:1:b),a:1:1000,acap(a:1:1000),a:1:b,mcap(a:1:b),'magenta')
         plot(app.distribution,-1:0.00001:1,dcap,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 1]) ;
        ylim(app.Data,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.Data,'AS Conv','Conv in Prob','MS Conv')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.convergence = uiaxes(app.GridLayout);
            title(app.convergence, 'REALIZATION BAND')
            xlabel(app.convergence, 'SAMPLES (N)')
            ylabel(app.convergence, {'|X(n'; 'w) - X(w)| (BAND)'})
            app.Data = uiaxes(app.GridLayout);
            title(app.Data, 'CONVERGENCE')
            xlabel(app.Data, 'SAMPLES(N)')
            ylabel(app.Data, 'PS AS MS')
            app.distribution = uiaxes(app.GridLayout);
            title(app.distribution, 'DISTRIBUTION CONVERGENCE (CDF)')
            xlabel(app.distribution, 'SAMPLES (N)')
            ylabel(app.distribution, 'F(y)')
            app.NormalButton = uibutton(app.GridLayout, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.UniformButton = uibutton(app.GridLayout, 'push');
            app.UniformButton.ButtonPushedFcn = createCallbackFcn(app, @UniformButtonPushed, true);
            app.UniformButton.Text = 'Uniform';
            app.ExponentialButton = uibutton(app.GridLayout, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Realizations : 500'; 'Samples (N): 2000  '};
            app.ZoomToEditFieldLabel = uilabel(app.GridLayout);
            app.ZoomToEditFieldLabel.Text = 'Zoom To';
            app.ZoomTo = uieditfield(app.GridLayout, 'numeric');
            app.ZoomTo.Limits = [100 2000];
            app.ZoomTo.Value = 2000;
            app.ZoomFromEditFieldLabel = uilabel(app.GridLayout);
            app.ZoomFromEditFieldLabel.Text = 'Zoom From';
            app.ZoomFrom = uieditfield(app.GridLayout, 'numeric');
            app.ZoomFrom.Limits = [1 1000];
            app.ZoomFrom.Value = 1;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'probability.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p3_convergence_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end